classdef ImageAcquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ImageTab                      matlab.ui.container.Tab
        Plot1DropDown                 matlab.ui.control.DropDown
        Plot1DropDownLabel            matlab.ui.control.Label
        Axes2DropDown                 matlab.ui.control.DropDown
        Axes2DropDownLabel            matlab.ui.control.Label
        Axes1DropDown                 matlab.ui.control.DropDown
        Axes1Label                    matlab.ui.control.Label
        UpdateCamerasButton           matlab.ui.control.Button
        StatusLamp                    matlab.ui.control.Lamp
        StatusLampLabel               matlab.ui.control.Label
        PauseContinueButton           matlab.ui.control.StateButton
        StopButton                    matlab.ui.control.StateButton
        SaveButton                    matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        SmallAxes2                    matlab.ui.control.UIAxes
        SmallAxes1                    matlab.ui.control.UIAxes
        BigAxes2                      matlab.ui.control.UIAxes
        BigAxes1                      matlab.ui.control.UIAxes
        SettingsTab                   matlab.ui.container.Tab
        TriggermodeDropDown           matlab.ui.control.DropDown
        TriggermodeDropDownLabel      matlab.ui.control.Label
        AcquisitiontimeoutsSpinner    matlab.ui.control.Spinner
        AcquisitiontimeoutsSpinnerLabel  matlab.ui.control.Label
        AcquisitionmodeDropDown       matlab.ui.control.DropDown
        AcquisitionmodeDropDownLabel  matlab.ui.control.Label
        ExposuresSpinner              matlab.ui.control.Spinner
        ExposuresSpinnerLabel         matlab.ui.control.Label
        SelectcameraButtonGroup       matlab.ui.container.ButtonGroup
        ZeluxButton                   matlab.ui.control.RadioButton
        Andor19331Button              matlab.ui.control.RadioButton
        Andor19330Button              matlab.ui.control.RadioButton
        BackgroundshotCheckBox        matlab.ui.control.CheckBox
        DatasetsizeSpinner            matlab.ui.control.Spinner
        DatasetsizeSpinnerLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        Live = struct()
        Data = struct()
        Layout = struct()
        Handle = {}
    end
    
    methods (Access = private)

        function initializeAcquisition(app)
            % Check if there is stored data
            if isfield(app.Data, 'Image')
                answer = questdlg('Start a new acquisition will erase the data in memory. Would you like to save it before proceeding?', ...
                    'Image Aquisition App', ...
                    'Yes', 'No', 'Yes');
                switch answer
                    case 'Yes'
                        saveData(app.Data, app.Live)
                    case 'No'
                end
            end
            
            % Initialize camera and Data Config
            camera = app.SelectcameraButtonGroup.SelectedObject.Text;
            external_trigger = strcmp(app.TriggermodeDropDown.Value, 'External');
            switch camera
                case {'Andor 19330', 'Andor 19331'}
                    serial = str2double(camera(7:end));
                    initializeAndor()
                    setCurrentAndor(serial)
                    switch app.AcquisitionmodeDropDown.Value
                        case 'Full frame'
                            num_frames = 1;
                        case 'FK 2'
                            num_frames = 2;
                        case 'FK 4'
                            num_frames = 4;
                        case 'FK 8'
                            num_frames = 8;
                    end
                    setModeFK("num_frames",num_frames, ...
                        "exposure",app.ExposuresSpinner.Value, ...
                        "external_trigger",external_trigger)
                    app.Data.Config = getAcquisitionConfig("num_frames",num_frames);                
                case 'Zelux'
                    app.Data.Config = struct(...
                        'Exposure', app.ExposuresSpinner.Value, ...
                        'XPixels', 1440, ...
                        'YPixels', 1080);
                    if ~isempty(app.Handle)
                        closeZelux(app.Handle{:})
                    end
                    [tlCameraSDK, tlCamera] = initializeZelux('exposure',app.ExposuresSpinner.Value, ...
                        'external_trigger',external_trigger);
                    app.Handle = {tlCameraSDK, tlCamera};
            end
            app.Data.Config.MaxImage = app.DatasetsizeSpinner.Value;
            app.Data.Config.External = external_trigger;
            app.Data.Config.Serial = camera;
            app.Data.Config.Background = app.BackgroundshotCheckBox.Value;
            fprintf('Camera Config for acquisition:\n')
            disp(app.Data.Config)
            
            % Initialize Data
            app.Data.Image = zeros(app.Data.Config.XPixels, app.Data.Config.YPixels, app.Data.Config.MaxImage);
            if app.Data.Config.Background
                app.Data.Background = zeros(app.Data.Config.XPixels, app.Data.Config.YPixels, app.Data.Config.MaxImage);
            end
            
            % Initialize statistics for live updates
            app.Live.Current = 0;
            app.Live.MeanCount = nan(1, app.Data.Config.MaxImage);
        
        end

        function image = acquireImage(app)
            if strcmp(app.Data.Config.Serial, 'Zelux')
                image = acquireZeluxImage(app.Handle{2}, "timeout",app.AcquisitiontimeoutsSpinner.Value);
            else
                image = acquireAndorImage("timeout",app.AcquisitiontimeoutsSpinner.Value);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startApp(app)
            
        end

        % Button pushed function: RunButton
        function start(app, event)
            
            % Initialize the acquisition
            initializeAcquisition(app)
            app.StatusLamp.Color = [1, 1, 0];

            % Run continuously until aborted
            while ~app.StopButton.Value
                while ~app.PauseContinueButton.Value && ~app.StopButton.Value
                    tic
                    
                    app.StatusLamp.Color = [1, 1, 0];
                    
                    % Acquire data
                    image = acquireImage(app);
                    if app.BackgroundshotCheckBox.Value
                        background = acquireImage(app);
                    end
                    
                    app.StatusLamp.Color = [0, 1, 0];

                    % Update data storage
                    app.Live.Current = app.Live.Current + 1;
                    if app.Live.Current <= app.Data.Config.MaxImage
                        app.Data.Image(:,:,app.Live.Current) = image;
                        if app.Data.Config.Background
                            app.Data.Background(:,:,app.Live.Current) = background;
                        end
                    else
                        % If the current shot is above the max, shift the data storage
                        app.Data.Image = circshift(app.Data.Image, -1, 3);
                        app.Data.Image(:, :, end) = image;
                        if app.Data.Config.Background
                            app.Data.Background = circshift(app.Data.Background, -1, 3);
                            app.Data.Background(:, :, end) = background;
                        end                        
                    end

                    % Update layout
                    signal = image - background;

                    imagesc(app.BigAxes1, image)
                    app.BigAxes1.DataAspectRatio = [1, 1, 1];
                    app.BigAxes1.Title.String = sprintf('Current image: %d/%d', app.Live.Current, app.Data.Config.MaxImage);
                    colorbar(app.BigAxes1)

                    imagesc(app.BigAxes2, signal)
                    app.BigAxes2.DataAspectRatio = [1, 1, 1];
                    app.BigAxes2.Title.String = 'Current signal';
                    colorbar(app.BigAxes2)
                    clim(app.BigAxes2, [-10 100])
                    drawnow
                    
                    pause(0.1)
                    
                    toc
                end

                pause(0.1)
            end

            fprintf('Acquisition stopped\n')
            app.StatusLamp.Color = [1, 0, 0];
            
            if ~isempty(app.Handle)
                closeZelux(app.Handle{:})
                app.Handle = {};
            end
            app.StopButton.Value = false;
            
        end

        % Close request function: UIFigure
        function closeUI(app, event)
            if ~isempty(app.Handle)
                closeZelux(app.Handle{:})
            end
            delete(app)
        end

        % Button pushed function: SaveButton
        function save(app, event)
            saveData(app.Data, app.Live)
        end

        % Button pushed function: UpdateCamerasButton
        function updateCameras(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeUI, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.BigAxes1 = uiaxes(app.ImageTab);
            app.BigAxes2 = uiaxes(app.ImageTab);
            title(app.BigAxes2, ' ')
            app.SmallAxes1 = uiaxes(app.ImageTab);
            xlabel(app.SmallAxes1, 'X')
            ylabel(app.SmallAxes1, 'Y')
            zlabel(app.SmallAxes1, 'Z')
            app.SmallAxes2 = uiaxes(app.ImageTab);
            xlabel(app.SmallAxes2, 'X')
            ylabel(app.SmallAxes2, 'Y')
            zlabel(app.SmallAxes2, 'Z')
            app.RunButton = uibutton(app.ImageTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.RunButton.Text = 'Run';
            app.SaveButton = uibutton(app.ImageTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveButton.Text = 'Save';
            app.StopButton = uibutton(app.ImageTab, 'state');
            app.StopButton.Text = 'Stop';
            app.PauseContinueButton = uibutton(app.ImageTab, 'state');
            app.PauseContinueButton.Text = 'Pause / Continue';
            app.StatusLampLabel = uilabel(app.ImageTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ImageTab);
            app.UpdateCamerasButton = uibutton(app.ImageTab, 'push');
            app.UpdateCamerasButton.ButtonPushedFcn = createCallbackFcn(app, @updateCameras, true);
            app.UpdateCamerasButton.Text = 'Update Cameras';
            app.Axes1Label = uilabel(app.ImageTab);
            app.Axes1Label.Text = 'Axes 1';
            app.Axes1DropDown = uidropdown(app.ImageTab);
            app.Axes1DropDown.Items = {'Image1', 'Image2', 'Signal'};
            app.Axes1DropDown.Value = 'Image1';
            app.Axes2DropDownLabel = uilabel(app.ImageTab);
            app.Axes2DropDownLabel.Text = 'Axes 2';
            app.Axes2DropDown = uidropdown(app.ImageTab);
            app.Axes2DropDown.Items = {'Image1', 'Image2', 'Signal'};
            app.Axes2DropDown.Value = 'Image1';
            app.Plot1DropDownLabel = uilabel(app.ImageTab);
            app.Plot1DropDownLabel.Text = 'Plot 1';
            app.Plot1DropDown = uidropdown(app.ImageTab);
            app.Plot1DropDown.Items = {'MeanCount', 'MaxCount'};
            app.Plot1DropDown.Value = 'MeanCount';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.DatasetsizeSpinnerLabel = uilabel(app.SettingsTab);
            app.DatasetsizeSpinnerLabel.Text = 'Dataset size';
            app.DatasetsizeSpinner = uispinner(app.SettingsTab);
            app.DatasetsizeSpinner.ValueDisplayFormat = '%d';
            app.DatasetsizeSpinner.Value = 20;
            app.BackgroundshotCheckBox = uicheckbox(app.SettingsTab);
            app.BackgroundshotCheckBox.Text = 'Background shot';
            app.BackgroundshotCheckBox.Value = true;
            app.SelectcameraButtonGroup = uibuttongroup(app.SettingsTab);
            app.SelectcameraButtonGroup.Title = 'Select camera';
            app.Andor19330Button = uiradiobutton(app.SelectcameraButtonGroup);
            app.Andor19330Button.Text = 'Andor 19330';
            app.Andor19331Button = uiradiobutton(app.SelectcameraButtonGroup);
            app.Andor19331Button.Text = 'Andor 19331';
            app.ZeluxButton = uiradiobutton(app.SelectcameraButtonGroup);
            app.ZeluxButton.Text = 'Zelux';
            app.ZeluxButton.Value = true;
            app.ExposuresSpinnerLabel = uilabel(app.SettingsTab);
            app.ExposuresSpinnerLabel.Text = 'Exposure (s)';
            app.ExposuresSpinner = uispinner(app.SettingsTab);
            app.ExposuresSpinner.Step = 0.1;
            app.ExposuresSpinner.Value = 1e-06;
            app.AcquisitionmodeDropDownLabel = uilabel(app.SettingsTab);
            app.AcquisitionmodeDropDownLabel.Text = 'Acquisition mode';
            app.AcquisitionmodeDropDown = uidropdown(app.SettingsTab);
            app.AcquisitionmodeDropDown.Items = {'Full frame', 'FK 2', 'FK 4', 'FK 8'};
            app.AcquisitionmodeDropDown.Value = 'Full frame';
            app.AcquisitiontimeoutsSpinnerLabel = uilabel(app.SettingsTab);
            app.AcquisitiontimeoutsSpinnerLabel.Text = 'Acquisition time out (s)';
            app.AcquisitiontimeoutsSpinner = uispinner(app.SettingsTab);
            app.AcquisitiontimeoutsSpinner.Value = 20;
            app.TriggermodeDropDownLabel = uilabel(app.SettingsTab);
            app.TriggermodeDropDownLabel.Text = 'Trigger mode';
            app.TriggermodeDropDown = uidropdown(app.SettingsTab);
            app.TriggermodeDropDown.Items = {'External', 'Internal'};
            app.TriggermodeDropDown.Value = 'External';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageAcquisition

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startApp)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end